classdef PID_MOTOR_CONTROL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PID_GUI                    matlab.ui.Figure
        PIDCONTROLLEDMOTORCONTROLPANELLabel  matlab.ui.control.Label
        MOTORSTOPButton            matlab.ui.control.Button
        UIAxes                     matlab.ui.control.UIAxes
        ClockwiseButton_2          matlab.ui.control.Button
        CounterClockwiseButton_2   matlab.ui.control.Button
        MotorDirectionLabel        matlab.ui.control.Label
        SendSpeedButton            matlab.ui.control.Button
        RESETButton                matlab.ui.control.Button
        SetSpeedRPMEditFieldLabel  matlab.ui.control.Label
        SetSpeedRPMEditField       matlab.ui.control.NumericEditField
        SHOWGRAPHButton            matlab.ui.control.Button
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            instrfind;
            delete(instrfind);
            global a;
            global x;
            a=serial('COM5', 'BAUD',9600 );
            pause(2);
            fopen(a);
            
        end

        % Button pushed function: ClockwiseButton_2
        function ClockwiseButton_2Pushed(app, event)
            global a;
            global x;
            fwrite(a, '600' );
            pause(2);


        end

        % Button pushed function: CounterClockwiseButton_2
        function CounterClockwiseButton_2Pushed(app, event)
            global a;
            fwrite(a,'500');
            pause(2);
            
        end

        % Button pushed function: MOTORSTOPButton
        function MOTORSTOPButtonPushed(app, event)
            global a;
            fwrite(a,'700');
            pause(1);
            
        end

        % Callback function: PID_GUI
        function PID_GUICloseRequest(app, event)
            delete(app);
            fclose(a);
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            global a;
            global x;
            x = 0;
            fwrite(a,'800');
            pause(2)
        end

        % Button pushed function: SHOWGRAPHButton
        function SHOWGRAPHButtonPushed(app, event)
            global a;
            time = 1:1:30;
            h = animatedline(app.UIAxes, 'MaximumNumPoint', 100);
            axis([0 100 0 100]);
            x = linspace(0,100,200);
            pause (1);
            for k = 1:length(x);
                read=fread(a, 1);
                addpoints(h,x(k),read);
                drawnow;
            end
            
        end

        % Button pushed function: SendSpeedButton
        function SendSpeedButtonPushed(app, event)
            global x;
            global a;
            x = app.SetSpeedRPMEditField.Value;
            fprintf(a,'%u',x);
            pause(2);
        end

        % Value changed function: SetSpeedRPMEditField
        function SetSpeedRPMEditFieldValueChanged(app, event)
            global x;
            global a;
            x = app.SetSpeedRPMEditField.Value;
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PID_GUI = uifigure;
            app.PID_GUI.Name = 'PID_MOTOR_CONTROL';
            app.PID_GUI.CloseRequestFcn = createCallbackFcn(app, @PID_GUICloseRequest, true);
            app.PID_GUI.SizeChangedFcn = createCallbackFcn(app, @PID_GUICloseRequest, true);
            app.PIDCONTROLLEDMOTORCONTROLPANELLabel = uilabel(app.PID_GUI);
            app.PIDCONTROLLEDMOTORCONTROLPANELLabel.Text = 'PID CONTROLLED MOTOR CONTROL PANEL';
            app.MOTORSTOPButton = uibutton(app.PID_GUI, 'push');
            app.MOTORSTOPButton.ButtonPushedFcn = createCallbackFcn(app, @MOTORSTOPButtonPushed, true);
            app.MOTORSTOPButton.Text = 'MOTOR STOP ';
            app.UIAxes = uiaxes(app.PID_GUI);
            title(app.UIAxes, 'PID CONTROLLED SPEED GRAPH')
            xlabel(app.UIAxes, 'TIME')
            ylabel(app.UIAxes, 'SPEED(RPM)')
            app.UIAxes.XLim = [0 120];
            app.UIAxes.XLimMode = 'manual';
            app.UIAxes.YLim = [0 100];
            app.UIAxes.YLimMode = 'manual';
            app.ClockwiseButton_2 = uibutton(app.PID_GUI, 'push');
            app.ClockwiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClockwiseButton_2Pushed, true);
            app.ClockwiseButton_2.Text = 'Clockwise';
            app.CounterClockwiseButton_2 = uibutton(app.PID_GUI, 'push');
            app.CounterClockwiseButton_2.ButtonPushedFcn = createCallbackFcn(app, @CounterClockwiseButton_2Pushed, true);
            app.CounterClockwiseButton_2.Text = 'Counter Clockwise';
            app.MotorDirectionLabel = uilabel(app.PID_GUI);
            app.MotorDirectionLabel.Text = 'Motor Direction';
            app.SendSpeedButton = uibutton(app.PID_GUI, 'push');
            app.SendSpeedButton.ButtonPushedFcn = createCallbackFcn(app, @SendSpeedButtonPushed, true);
            app.SendSpeedButton.Text = 'Send Speed';
            app.RESETButton = uibutton(app.PID_GUI, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            app.SetSpeedRPMEditFieldLabel = uilabel(app.PID_GUI);
            app.SetSpeedRPMEditFieldLabel.Text = 'Set Speed (RPM)';
            app.SetSpeedRPMEditField = uieditfield(app.PID_GUI, 'numeric');
            app.SetSpeedRPMEditField.ValueChangedFcn = createCallbackFcn(app, @SetSpeedRPMEditFieldValueChanged, true);
            app.SetSpeedRPMEditField.Limits = [0 120];
            app.SetSpeedRPMEditField.ValueDisplayFormat = '%11.3g';
            app.SetSpeedRPMEditField.Value = 80;
            app.SHOWGRAPHButton = uibutton(app.PID_GUI, 'push');
            app.SHOWGRAPHButton.ButtonPushedFcn = createCallbackFcn(app, @SHOWGRAPHButtonPushed, true);
            app.SHOWGRAPHButton.Text = 'SHOW GRAPH';
        end
    end

    methods (Access = public)

        % Construct app
        function app = PID_MOTOR_CONTROL

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PID_GUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PID_GUI)
        end
    end
end